classdef DefectDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        OpenButton  matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.jpg','MultiSelect','on');

            if ~isempty(filename) && length(filename)~=1
                load('detector.mat','detector');
                
                if iscell(filename)
                    N = length(filename);
                    for n = 1:N
                        I = imread([path filename{n}]);
                        if ~isempty(I)
                            title(filename{n},'parent',app.UIAxes);
                            imshow(I,'parent',app.UIAxes);
                            pause(0.1);
                            [bboxes,scores] = detect(detector,I);
                            img = I;
                            if(~isempty(bboxes))
                                img = insertObjectAnnotation(img,'rectangle',bboxes,scores);
                            end
                            imshow(img,'parent',app.UIAxes);
                            pause(0.1);
                        end
                    end
                else
                    I = imread([path filename]);
                    if ~isempty(I)
                        title(filename,'parent',app.UIAxes);
                        imshow(I,'parent',app.UIAxes);
                        pause(0.1);
                        [bboxes,scores] = detect(detector,I);
                        img = I;
                        if(~isempty(bboxes))
                            img = insertObjectAnnotation(img,'rectangle',bboxes,scores);
                        end
                        imshow(img,'parent',app.UIAxes);
                        pause(0.1);
                    end
                end
            end           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {54, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefectDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end