classdef DefectDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        OpenButton  matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        TabGroup    matlab.ui.container.TabGroup
        ImageTab    matlab.ui.container.Tab
        UIAxes      matlab.ui.control.UIAxes
        ReportTab   matlab.ui.container.Tab
        TextArea    matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, path] = uigetfile('*.jpg','MultiSelect','on');

            if ~isempty(filename) && length(filename)~=1
                load('detector.mat','detector');
                if ~iscell(filename)
                    filename = cellstr(filename);
                end
                N = length(filename);
                for n = 1:N
                    app.TextArea.Value = [app.TextArea.Value; cellstr(filename{n})];
                    I = imread([path filename{n}]);
                    if ~isempty(I)
                        title(filename{n},'parent',app.UIAxes);
                        imshow(I,'parent',app.UIAxes);
                        pause(0.1);
                        [bboxes,scores] = detect(detector,I);
                        if(~isempty(bboxes))
                            ang = [];
                            for m=1:size(bboxes,1)                                
                                
                                
                                k = 0.8;
                        
%                                 [h,w] = size(I);
%                                 Kh = h/416; Kw = w/416;
                                
                                roi = [bboxes(m,1),bboxes(m,2),bboxes(m,3),bboxes(m,4)];
                                big_roi = [roi(1)-roi(3)*k,roi(2)-roi(4)*k,roi(3)*(2*k+1),roi(4)*(2*k+1)];
                                big_roi(big_roi<1)=1;
                                Ic = imcrop(I,big_roi);
%                                 figure; imshow(Ic);
                                
                                ang(m) = calc_angle(Ic);
                                Icrot = imrotate(Ic, ang(m)+90);
                                [h,w,~] = size(Icrot);
                                Icrot = imcrop(Icrot,[(w/2-w/4),(h/2-h/4),w/2,h/2]);
%                                 Icrot = diff(Icrot,1);
%                                 figure; imshow(Icrot,[]);
%                                 figure(10), imshow(Icrot);

                                
                                BW = imbinarize(rgb2gray(Icrot), 'adaptive');
%                                 figure(11), imshow(BW);
                                
                                SE = strel(ones(2,2));
                                
                                % BW = imclose(BW,SE);
                                BW = imerode(BW,SE);
                                BW = imdilate(BW,SE);
                                BW = bwareaopen(BW, 25);
%                                 figure(12), imshow(BW);
                                
                                sBW = sum(BW);
                                if length(sBW)>16
                                    sBW = vector_average_filtration(sBW,16);
                                end
                                [~, loc] = findpeaks(sBW, 'MinPeakDistance', 16);
                                
                                
%                                 figure(), plot(sBW); findpeaks(sBW, 'MinPeakDistance', 16);
%                                 diff(loc)
                                num_isol(m) = length(loc);
                                
%                                 if (num_isol(m)<6)
%                                     drawrectangle('parent',app.UIAxes, 'Position',bboxes(m,:), 'InteractionsAllowed', 'none', 'Color' ,'r');
%                                 else
                                    drawrectangle('parent',app.UIAxes, 'Position',bboxes(m,:), 'InteractionsAllowed', 'none', 'Color' ,'b');
%                                 end
                            end
                            
                            app.TextArea.Value = [app.TextArea.Value; cellstr(['Обнаружено ' num2str(length(num_isol)) ' изоляторов'])];
                            if abs(diff([ang,ang(1)]))>45 
                                app.TextArea.Value = [app.TextArea.Value; cellstr(['Внимание возможен обрыв провода.'])];
%                             elseif sum(num_isol<6)>0
%                                 app.TextArea.Value = [app.TextArea.Value; cellstr(['Внимание дефект изолятора.'])];
                            else
                                app.TextArea.Value = [app.TextArea.Value; cellstr(['Дефектов не обнаружено'])];
                            end
                            
                            
                        end
                        pause(0.1);
                        
                    end
                end
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {54, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.UIAxes = uiaxes(app.ImageTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.TextArea = uitextarea(app.ReportTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefectDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end